classdef HomographyCalculator_v0p1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes_Img1                  matlab.ui.control.UIAxes
        UIAxes_Img2                  matlab.ui.control.UIAxes
        SelectImage1Button           matlab.ui.control.Button
        SelectImage2Button           matlab.ui.control.Button
        UIAxes_Res                   matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
        HomographyMatrixPanel        matlab.ui.container.Panel
        EditField_1                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_9                  matlab.ui.control.NumericEditField
        OriginalHomographyButton     matlab.ui.control.Button
        NormalizedHomographyButton   matlab.ui.control.Button
        PointsSelectedPanel          matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        CorrespondencesLabel         matlab.ui.control.Label
        Spinner                      matlab.ui.control.Spinner
        SelectPointsButton           matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        UITableStatus                matlab.ui.control.Table
        LabelUIA                     matlab.ui.control.Label
        NormalizeHomographyCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Image1Selected = 0 ;
        Image2Selected = 0 ;
        img1Points ;
        img2Points ;
        im1;
        im2;
    end
    
    methods (Access = private)
        
        function results = addStatusLine(app, newLineData)
            timestamp = datestr(now,'HH:MM:SS');
            concatString = strcat('[',timestamp,']   ', newLineData);
            app.UITableStatus.Data = [app.UITableStatus.Data; concatString];
            results = 1;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning off MATLAB:structOnObject
            
            app.UITableStatus.Data = {'Application Started '};
            imEmpty = imread("empty.jpg");
            imshow(imEmpty,'Parent',app.UIAxes_Img1);
            imshow(imEmpty,'Parent',app.UIAxes_Img2);
            imEmpty = imread("empty2.png");
            imshow(imEmpty, 'Parent',app.UIAxes_Res);
            
            app.LabelUIA.Text = '';            
        end

        % Button pushed function: SelectImage1Button
        function SelectImage1ButtonPushed(app, event)
            [filename,user_canceled] = imgetfile('InitialPath','\');
            
            if (~user_canceled )
                addStatusLine(app, 'Image 1 Selected');
                
                app.im1 = imread(filename);
                imshow(app.im1,'Parent',app.UIAxes_Img1);
                app.Image1Selected = 1;
            end
            
        end

        % Button pushed function: SelectImage2Button
        function SelectImage2ButtonPushed(app, event)
            [filename2,user_canceled2] = imgetfile('InitialPath','\');
            
            if (~user_canceled2 )
                addStatusLine(app, 'Image 2 Selected');
                
                app.im2 = imread(filename2);
                imshow(app.im2,'Parent',app.UIAxes_Img2);
                app.Image2Selected = 1;
            end
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
            if (app.Image1Selected==1 && app.Image2Selected==1)
                
                app.img1Points = zeros(app.Spinner.Value, 2);
                app.img2Points = zeros(app.Spinner.Value, 2);
                
                app.LabelUIA.Text = 'Select Points From Image 1';
                for i=1:1:app.Spinner.Value
                    roiPOC = drawpoint(app.UIAxes_Img1);
                    PosPOC=round(get(roiPOC ,'Position'));
%                     roiPOC.Label = strcat(int2str(i), ': [', int2str(PosPOC(1)) , ',' , int2str(PosPOC(2)) ,']');
                    roiPOC.Label = int2str(i);
                    app.img1Points(i,:) = [PosPOC(1) PosPOC(2)];
                    drawnow;
                end
                addStatusLine(app, 'Image 1 Points Selected');
                
                app.LabelUIA.Text = 'Select Points From Image 2';
                for i=1:1:app.Spinner.Value
                    roiPOC = drawpoint(app.UIAxes_Img2);
                    PosPOC=round(get(roiPOC ,'Position'));
%                     roiPOC.Label = strcat(int2str(i), ': [', int2str(PosPOC(1)) , ',' , int2str(PosPOC(2)) ,']');
                    roiPOC.Label = int2str(i);
                    app.img2Points(i,:) = [PosPOC(1) PosPOC(2)];
                    drawnow;
                end
                addStatusLine(app, 'Image 2 Points Selected');
                app.LabelUIA.Text = '';
                
                app.UITable.Data = [app.img1Points app.img2Points];
                
            else
                uialert(app.UIFigure,'One or more images are not selected','Error !');
            end
        end

        % Button pushed function: OriginalHomographyButton
        function OriginalHomographyButtonPushed(app, event)
            
            mat_A = zeros(2*app.Spinner.Value ,9);
            
            % compute homography
            for i=1:1:app.Spinner.Value
                % construct sub-matrix
                u = app.img1Points(i,1);
                v = app.img1Points(i,2);
                u_dash = app.img2Points(i,1);
                v_dash = app.img2Points(i,2);
                
                sub_mat_A = [0 0 0 -u -v -1 v_dash*u v_dash*v v_dash;
                             u v 1 0 0 0 -u_dash*u -u_dash*v -u_dash];
                mat_A((i*2)-1: i*2,:) = sub_mat_A;
            end
            
            [U,S,V] = svd(mat_A);
            
            h_extracted = V(:,9);
            H = [h_extracted(1) h_extracted(2)  h_extracted(3);
                 h_extracted(4) h_extracted(5)  h_extracted(6);
                 h_extracted(7) h_extracted(8)  h_extracted(9)]; 
             
             if (app.NormalizeHomographyCheckBox.Value == 1)
                    H = H./h_extracted(9);
             end
            
             tform = projective2d(H');
             imWarped = imwarp(app.im1, tform, 'OutputView',imref2d(size(app.im1))); 
             imshow(imWarped,'Parent',app.UIAxes_Res) ;
             
             app.EditField_1.Value = H(1,1);
             app.EditField_2.Value = H(1,2);
             app.EditField_3.Value = H(1,3);
             app.EditField_4.Value = H(2,1);
             app.EditField_5.Value = H(2,2);
             app.EditField_6.Value = H(2,3);
             app.EditField_7.Value = H(3,1);
             app.EditField_8.Value = H(3,2);
             app.EditField_9.Value = H(3,3);
             
             addStatusLine(app, 'Original Homography Calculated');
        end

        % Button pushed function: NormalizedHomographyButton
        function NormalizedHomographyButtonPushed(app, event)
            
            %% ==================================================================
            % [u,v] get centroid of points 
            x_centroid = mean(app.img1Points(:,1));
            y_centroid = mean(app.img1Points(:,2));
            
            reg_points(:,1) = app.img1Points(:,1)-x_centroid;
            reg_points(:,2) = app.img1Points(:,2)-y_centroid;
            
            % get the scale factor
            reg_scale = (sqrt(2)*size(reg_points,1))/sum(sqrt(reg_points(:,1).^2 + reg_points(:,2).^2));
            
            % scale the points
            reg_points(:,1) = reg_scale*reg_points(:,1);
            reg_points(:,2) = reg_scale*reg_points(:,2);
            
            % construct the transformation
            reg_T = [reg_scale 0 -reg_scale*x_centroid; 0 reg_scale -reg_scale*y_centroid; 0 0 1];
            
            %% ==================================================================
            
            % [u_dash, v_dash] get centroid of points
            x_dash_centroid = mean(app.img2Points(:,1));
            y_dash_centroid = mean(app.img2Points(:,2));
            
            dash_points(:,1) = app.img2Points(:,1)-x_dash_centroid;
            dash_points(:,2) = app.img2Points(:,2)-y_dash_centroid;
            
            % get the scale factor
            dash_scale = (sqrt(2)*size(dash_points,1))/sum(sqrt(dash_points(:,1).^2 + dash_points(:,2).^2));
            
            % scale the points
            dash_points(:,1) = dash_scale*dash_points(:,1);
            dash_points(:,2) = dash_scale*dash_points(:,2);
            
            % construct the transformation
            dash_T = [dash_scale 0 -dash_scale*x_dash_centroid; 0 dash_scale -dash_scale*y_dash_centroid; 0 0 1];
            
            %% ==================================================================
            mat_A = zeros(2*app.Spinner.Value ,9);
            
            % compute homography
            for i=1:1:app.Spinner.Value
                % construct sub-matrix
                u = reg_points(i,1);
                v = reg_points(i,2);
                u_dash = dash_points(i,1);
                v_dash = dash_points(i,2);
                
                sub_mat_A = [0 0 0 -u -v -1 v_dash*u v_dash*v v_dash;
                             u v 1 0 0 0 -u_dash*u -u_dash*v -u_dash];
                mat_A((i*2)-1: i*2,:) = sub_mat_A;
            end
            
            [U,S,V] = svd(mat_A);
            
            h_extracted = V(:,9);
            
            H_telda = [ h_extracted(1) h_extracted(2)  h_extracted(3);
                        h_extracted(4) h_extracted(5)  h_extracted(6);
                        h_extracted(7) h_extracted(8)  h_extracted(9)]; 
             
             % denomalize the homography
%              H = inv(dash_T)*H_telda*reg_T;
             H = inv(dash_T)*H_telda*reg_T;
             
             if (app.NormalizeHomographyCheckBox.Value == 1)
                    H = H./H(3,3);
             end
            
             tform = projective2d(H');
             imWarped = imwarp(app.im1, tform, 'OutputView',imref2d(size(app.im1))); 
             imshow(imWarped,'Parent',app.UIAxes_Res) ;
             
             app.EditField_1.Value = H(1,1);
             app.EditField_2.Value = H(1,2);
             app.EditField_3.Value = H(1,3);
             app.EditField_4.Value = H(2,1);
             app.EditField_5.Value = H(2,2);
             app.EditField_6.Value = H(2,3);
             app.EditField_7.Value = H(3,1);
             app.EditField_8.Value = H(3,2);
             app.EditField_9.Value = H(3,3);
             
             addStatusLine(app, 'Normalized Homography Calculated');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_Img1 = uiaxes(app.UIFigure);
            title(app.UIAxes_Img1, 'Image 1')
            xlabel(app.UIAxes_Img1, '')
            ylabel(app.UIAxes_Img1, '')
            app.UIAxes_Img2 = uiaxes(app.UIFigure);
            title(app.UIAxes_Img2, 'Image 2')
            xlabel(app.UIAxes_Img2, '')
            ylabel(app.UIAxes_Img2, '')
            app.SelectImage1Button = uibutton(app.UIFigure, 'push');
            app.SelectImage1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectImage1ButtonPushed, true);
            app.SelectImage1Button.Text = 'Select Image 1';
            app.SelectImage2Button = uibutton(app.UIFigure, 'push');
            app.SelectImage2Button.ButtonPushedFcn = createCallbackFcn(app, @SelectImage2ButtonPushed, true);
            app.SelectImage2Button.Text = 'Select Image 2';
            app.UIAxes_Res = uiaxes(app.UIFigure);
            title(app.UIAxes_Res, 'Warped Image')
            xlabel(app.UIAxes_Res, '')
            ylabel(app.UIAxes_Res, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=>';
            app.HomographyMatrixPanel = uipanel(app.UIFigure);
            app.HomographyMatrixPanel.Title = 'Homography Matrix';
            app.EditField_1 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_2 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_3 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_4 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_5 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_6 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_7 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_8 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.EditField_9 = uieditfield(app.HomographyMatrixPanel, 'numeric');
            app.OriginalHomographyButton = uibutton(app.HomographyMatrixPanel, 'push');
            app.OriginalHomographyButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalHomographyButtonPushed, true);
            app.OriginalHomographyButton.Text = 'Original Homography';
            app.NormalizedHomographyButton = uibutton(app.HomographyMatrixPanel, 'push');
            app.NormalizedHomographyButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizedHomographyButtonPushed, true);
            app.NormalizedHomographyButton.Text = 'Normalized Homography';
            app.PointsSelectedPanel = uipanel(app.UIFigure);
            app.PointsSelectedPanel.Title = 'Points Selected';
            app.UITable = uitable(app.PointsSelectedPanel);
            app.UITable.ColumnName = {'x-1'; 'y-1'; 'x-2'; 'y-2'};
            app.UITable.RowName = {};
            app.CorrespondencesLabel = uilabel(app.PointsSelectedPanel);
            app.CorrespondencesLabel.Text = 'Correspondences';
            app.Spinner = uispinner(app.PointsSelectedPanel);
            app.Spinner.Limits = [4 Inf];
            app.Spinner.Value = 4;
            app.SelectPointsButton = uibutton(app.PointsSelectedPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=>';
            app.UITableStatus = uitable(app.UIFigure);
            app.UITableStatus.ColumnName = {'Status'};
            app.UITableStatus.RowName = {};
            app.LabelUIA = uilabel(app.UIFigure);
            app.NormalizeHomographyCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeHomographyCheckBox.Text = 'Normalize Homography';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomographyCalculator_v0p1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end